classdef ABCD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SendingEndCalculationPanel      matlab.ui.container.Panel
        IphaseSendingALabel             matlab.ui.control.Label
        ISending                        matlab.ui.control.Label
        VphaseSendingVoltLabel          matlab.ui.control.Label
        VSending                        matlab.ui.control.Label
        CalculateSendingEndParametersButton  matlab.ui.control.Button
        RLCandLengthforABCDCalculationPanel_2  matlab.ui.container.Panel
        PowerFactorEditField            matlab.ui.control.NumericEditField
        PowerFactorEditFieldLabel       matlab.ui.control.Label
        pfDropDown                      matlab.ui.control.DropDown
        pfDropDownLabel                 matlab.ui.control.Label
        VphaseReceivingVoltEditField    matlab.ui.control.NumericEditField
        VphaseReceivingVoltEditFieldLabel  matlab.ui.control.Label
        PowerWattEditField              matlab.ui.control.NumericEditField
        PowerWattEditFieldLabel         matlab.ui.control.Label
        NameMohamedAhmedMahmoudMenazaaLabel  matlab.ui.control.Label
        ABCDParameterPanel              matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        EnterNewRLCButton               matlab.ui.control.RadioButton
        UseCalculatedRLCButton          matlab.ui.control.RadioButton
        DLabel                          matlab.ui.control.Label
        DParameter                      matlab.ui.control.Label
        CLabel_2                        matlab.ui.control.Label
        CParameter                      matlab.ui.control.Label
        BLabel                          matlab.ui.control.Label
        BParameter                      matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        AParameter                      matlab.ui.control.Label
        RLCandLengthforABCDCalculationPanel  matlab.ui.container.Panel
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        LengthKmEditField               matlab.ui.control.NumericEditField
        LengthKmEditFieldLabel          matlab.ui.control.Label
        InductanceHKmEditField          matlab.ui.control.NumericEditField
        InductanceHKmEditFieldLabel     matlab.ui.control.Label
        TLModelDropDown                 matlab.ui.control.DropDown
        TLModelDropDownLabel            matlab.ui.control.Label
        CapacitanceFKmEditField         matlab.ui.control.NumericEditField
        CapacitanceFKmEditFieldLabel    matlab.ui.control.Label
        ResistanceohmKmEditField        matlab.ui.control.NumericEditField
        ResistanceohmKmEditFieldLabel   matlab.ui.control.Label
        CalculateTLABCDParametersButton  matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        calculateTLParametersRLCButton  matlab.ui.control.Button
        ResistanceCalculationPanel      matlab.ui.container.Panel
        ResistivityEditField            matlab.ui.control.NumericEditField
        ResistivityEditFieldLabel       matlab.ui.control.Label
        NumberoflayrsEditField          matlab.ui.control.NumericEditField
        NumberoflayrsEditFieldLabel     matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        SpiralEffectCheckBox            matlab.ui.control.CheckBox
        SkinEffectCheckBox              matlab.ui.control.CheckBox
        CapacitanceandInductanceCalculatuinsPanel  matlab.ui.container.Panel
        CapacitanceGMREditField         matlab.ui.control.NumericEditField
        CapacitanceGMREditFieldLabel    matlab.ui.control.Label
        ConductorDistanceforsymmetricallineEditField  matlab.ui.control.NumericEditField
        ConductorDistanceforsymmetricallineEditFieldLabel  matlab.ui.control.Label
        ConductorRadiusEditField        matlab.ui.control.NumericEditField
        ConductorRadiusEditFieldLabel   matlab.ui.control.Label
        InductanceGMREditField          matlab.ui.control.NumericEditField
        InductanceGMREditFieldLabel     matlab.ui.control.Label
        BundledPanel                    matlab.ui.container.Panel
        BundleDistanceEditField         matlab.ui.control.NumericEditField
        BundleDistanceEditFieldLabel    matlab.ui.control.Label
        NumberofBundlesButtonGroup      matlab.ui.container.ButtonGroup
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        DistancesforunsymmetricalspacingPanel  matlab.ui.container.Panel
        D3EditField                     matlab.ui.control.NumericEditField
        D3EditFieldLabel                matlab.ui.control.Label
        D2EditField                     matlab.ui.control.NumericEditField
        D2EditFieldLabel                matlab.ui.control.Label
        D1EditField                     matlab.ui.control.NumericEditField
        D1EditFieldLabel                matlab.ui.control.Label
        ConductorTypeButtonGroup_2      matlab.ui.container.ButtonGroup
        SingleButton_2                  matlab.ui.control.RadioButton
        BundledButton_3                 matlab.ui.control.RadioButton
        ConductorSpacingButtonGroup     matlab.ui.container.ButtonGroup
        symmetricalButton               matlab.ui.control.RadioButton
        unsymmetricalButton             matlab.ui.control.RadioButton
        ConductorTypeButtonGroup        matlab.ui.container.ButtonGroup
        SolidButton                     matlab.ui.control.RadioButton
        StrandedButton                  matlab.ui.control.RadioButton
        ResultsPanel                    matlab.ui.container.Panel
        RLabel                          matlab.ui.control.Label
        ResistanceohmkmLabel            matlab.ui.control.Label
        NoteweassumetranspostionofthelineandweuseSIunitsLabel  matlab.ui.control.Label
        CapacitanceperphaseFkmLabel     matlab.ui.control.Label
        InductanceperphaseHkmLabel      matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        LLabel                          matlab.ui.control.Label
        TLParametersandModelingCalculatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ABCDParameters % Description
        Resistance % Description
        Capacitance % Description
        Inductance % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ConductorTypeButtonGroup
        function ConductorTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConductorTypeButtonGroup.SelectedObject;
            if (selectedButton == app.SolidButton)
                app.InductanceGMREditField.Enable = 'off';
                app.InductanceGMREditFieldLabel.Enable = 'off';
                app.CapacitanceGMREditField.Enable = 'off';
                app.CapacitanceGMREditFieldLabel.Enable = 'off';
                app.ConductorRadiusEditField.Enable = 'on';
                app.ConductorRadiusEditFieldLabel.Enable = 'on';
                app.NumberoflayrsEditField.Enable = 'off';
            else
                app.InductanceGMREditField.Enable = 'on';
                app.InductanceGMREditFieldLabel.Enable = 'on';
                app.CapacitanceGMREditField.Enable = 'on';
                app.CapacitanceGMREditFieldLabel.Enable = 'on';
                app.ConductorRadiusEditField.Enable = 'off';
                app.ConductorRadiusEditFieldLabel.Enable = 'off';
                app.NumberoflayrsEditField.Enable = 'on';
            end
        end

        % Selection changed function: ConductorSpacingButtonGroup
        function ConductorSpacingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConductorSpacingButtonGroup.SelectedObject;
            if (selectedButton == app.symmetricalButton)
                app.DistancesforunsymmetricalspacingPanel.Enable = 'off';
                app.ConductorDistanceforsymmetricallineEditField.Enable = 'on';
                app.ConductorDistanceforsymmetricallineEditFieldLabel.Enable = 'on';
            else
                app.ConductorDistanceforsymmetricallineEditField.Enable = 'off';
                app.ConductorDistanceforsymmetricallineEditFieldLabel.Enable =  'off';
                app.DistancesforunsymmetricalspacingPanel.Enable = 'on';
            end
        end

        % Selection changed function: ConductorTypeButtonGroup_2
        function ConductorTypeButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ConductorTypeButtonGroup_2.SelectedObject;
            if (selectedButton == app.SingleButton_2)
                app.BundledPanel.Enable = 'off';
                
            else
                app.BundledPanel.Enable = 'on';
                
            end
        end

        % Button pushed function: calculateTLParametersRLCButton
        function calculateTLParametersRLCButtonPushed(app, event)
            Symmetry = app.ConductorSpacingButtonGroup.SelectedObject;
            IsStranded = app.ConductorTypeButtonGroup.SelectedObject;
            IsBundled = app.ConductorTypeButtonGroup_2.SelectedObject;
            resistivity = app.ResistivityEditField.Value;
            R_radius = app.RadiusEditField.Value;
            if (Symmetry == app.symmetricalButton)
                GMD = app.ConductorDistanceforsymmetricallineEditField.Value;
            else
                D1 = app.D1EditField.Value;
                D2 = app.D2EditField.Value;
                D3 = app.D3EditField.Value;
                GMD = (D1*D2*D3)^(1/3);
            end
            L_GMR = 0;
            C_GMR = 0;
            if (IsStranded == app.StrandedButton)
                L_GMR = app.InductanceGMREditField.Value;
                C_GMR = app.CapacitanceGMREditField.Value;
                num_of_layers = app.NumberoflayrsEditField.Value;
                if(IsBundled == app.BundledButton_3)

                    switch(app.NumberofBundlesButtonGroup.SelectedObject.Text)
                        case '2'
                            Area = pi*(R_radius^2)*(3*num_of_layers*(num_of_layers+1)+1)*2;
                        case '3'
                            Area = pi*(R_radius^2)*(3*num_of_layers*(num_of_layers+1)+1)*3;
                            
                        case '4'
                            Area = pi*(R_radius^2)*(3*num_of_layers*(num_of_layers+1)+1)*4;  
                    end
                else
                    Area = pi*(R_radius^2)*(3*num_of_layers*(num_of_layers+1)+1);
                end
                
            else
                radius = app.ConductorRadiusEditField.Value;
                r_dash = radius*0.7788;
                if(IsBundled == app.BundledButton_3)
                    Bundles_Distance = app.BundleDistanceEditField.Value;
                    switch(app.NumberofBundlesButtonGroup.SelectedObject.Text)
                        case '2'
                            L_GMR = (r_dash*Bundles_Distance)^(1/2);
                            C_GMR = (radius*Bundles_Distance)^(1/2);
                            Area = pi*(R_radius^2)*2;
                        case '3'
                            L_GMR = (r_dash*(Bundles_Distance^2))^(1/3);
                            C_GMR = (radius*(Bundles_Distance^2))^(1/3);
                            Area = pi*(R_radius^2)*3;
                            
                        case '4'
                            L_GMR = 1.09*(r_dash*(Bundles_Distance^3))^(1/4);
                            C_GMR = 1.09*(radius*(Bundles_Distance^3))^(1/4);
                            Area = pi*(R_radius^2)*4;
                    end
                else
                    L_GMR = r_dash;
                    C_GMR = radius;
                    Area = pi*(R_radius^2);
                end
            end
            if(Area ==0)
               app.RLabel.Text = "Area = 0 Error";
            else
                Resitance_Value = (resistivity*1000)/Area;
                if (app.SkinEffectCheckBox.Value)
                     Resitance_Value = Resitance_Value * 1.11;
                end
                if (app.SpiralEffectCheckBox.Value)
                     Resitance_Value = Resitance_Value * 1.02;
                end
                app.Resistance = Resitance_Value;
                app.RLabel.Text = string(Resitance_Value);
            end
            if (L_GMR==0 || C_GMR==0)
                app.LLabel.Text = "GMR = 0 Error";
                app.CLabel.Text = "GMR = 0 Error";
            else
                Capacitance_Result = (2*pi* 8.854*(10^(-12)))/(log(GMD/C_GMR))*1000;
                app.Capacitance = Capacitance_Result;
                app.CLabel.Text = string(Capacitance_Result);
                Inductance_Result = 2*(10^(-7))*log(GMD/L_GMR)*1000;
                app.Inductance = Inductance_Result;
                app.LLabel.Text = string(Inductance_Result);
                
            end
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if (selectedButton == app.CalculatetheResistanceButton)
                app.ResistanceCalculationPanel.Enable = "on";
                app.RLCandLengthforABCDCalculationPanel.Enable = 'off';
            else
                app.ResistanceCalculationPanel.Enable = "off";
                app.RLCandLengthforABCDCalculationPanel.Enable = 'on';
            end
            
        end

        % Callback function
        function ButtonGroupSelectionChanged2(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if (selectedButton == app.EnterNewRLCButton)
                app.ResistanceohmKmEditField.Enable = 'on';
                app.InductanceHKmEditField.Enable = 'on';
                app.CapacitanceFKmEditField.Enable = 'on';
            else
                app.ResistanceohmKmEditField.Enable = 'off';
                app.InductanceHKmEditField.Enable = 'off';
                app.CapacitanceFKmEditField.Enable = 'off';
            end
        end

        % Button pushed function: CalculateTLABCDParametersButton
        function CalculateTLABCDParametersButtonPushed(app, event)
            Calculated_or_New = app.ButtonGroup.SelectedObject;
            if(Calculated_or_New == app.EnterNewRLCButton)
                ABCD_Resistance = app.ResistanceohmKmEditField.Value;
                ABCD_Inductance = app.InductanceHKmEditField.Value;
                ABCD_Capaciatance = app.CapacitanceFKmEditField.Value;
            else
                ABCD_Resistance = app.Resistance;
                ABCD_Inductance = app.Inductance;
                ABCD_Capaciatance = app.Capacitance; 
            end
            
            ABCD_Length = app.LengthKmEditField.Value;
            Frequency = app.FrequencyHzEditField.Value;
            TL_Model = app.TLModelDropDown.Value;
            omega = 2*pi*Frequency;
            Z = ABCD_Resistance+(omega*ABCD_Inductance)*1i;
            Z = Z*ABCD_Length;
            Y = (omega*ABCD_Capaciatance)*ABCD_Length*1i;
            switch(TL_Model)
                case 'Short'
                  ABCD = [1 Z; 0 1];    
                case 'pi Medium'
                  ABCD = [1+(Z*Y)/2 Z; Y*(1+(Z*Y)/4) 1+(Z*Y)/2];    
                case 'T Medium'
                  ABCD = [1+(Z*Y)/2 Z*(1+(Z*Y)/4); Y 1+(Z*Y)/2];    
                case 'Long'
                  gama = sqrt(Y*Z);
                  Z_c = sqrt(Z/Y);
                  ABCD = [cosh(gama) Z_c*sinh(gama); (sinh(gama)/Z_c) cosh(gama)];
            end
            app.ABCDParameters = ABCD;
            A = ABCD(1,1);
            B = ABCD(1,2);
            C = ABCD(2,1);
            D = ABCD(2,2);
            app.AParameter.Text = string(A);
            app.BParameter.Text = string(B);
            app.CParameter.Text = string(C);
            app.DParameter.Text = string(D);
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged3(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if (selectedButton == app.EnterNewRLCButton)
                app.ResistanceohmKmEditField.Enable = 'on';
                app.InductanceHKmEditField.Enable = 'on';
                app.CapacitanceFKmEditField.Enable = 'on';
                app.ResistanceohmKmEditFieldLabel.Enable = 'on';
                app.InductanceHKmEditFieldLabel.Enable = 'on';
                app.CapacitanceFKmEditFieldLabel.Enable = 'on';
                
            else
                app.ResistanceohmKmEditField.Enable = 'off';
                app.InductanceHKmEditField.Enable = 'off';
                app.CapacitanceFKmEditField.Enable = 'off';
                app.ResistanceohmKmEditFieldLabel.Enable = 'off';
                app.InductanceHKmEditFieldLabel.Enable = 'off';
                app.CapacitanceFKmEditFieldLabel.Enable = 'off';
                
            end
        end

        % Button pushed function: 
        % CalculateSendingEndParametersButton
        function CalculateSendingEndParametersButtonPushed(app, event)
            ABCD = app.ABCDParameters;
            Vr_ph = app.VphaseReceivingVoltEditField.Value;
            Power_R = app.PowerWattEditField.Value;
            power_factor = app.PowerFactorEditField.Value;
            pf_type = app.pfDropDown.Value;
            I_ph = Power_R/(3*Vr_ph*power_factor);
            switch(pf_type)
                case 'Lead'
                  angle = acos(power_factor);    
                case 'Lag'
                  angle = -acos(power_factor);    
            end
            I_ph = I_ph*(cos(angle)+1i*sin(angle));
            Receiving_Vector = [Vr_ph;I_ph];
            Sending_Vector = ABCD*Receiving_Vector;
            app.VSending.Text = string(Sending_Vector(1));
            app.ISending.Text = string(Sending_Vector(2));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TLParametersandModelingCalculatorLabel = uilabel(app.UIFigure);
            app.TLParametersandModelingCalculatorLabel.Text = 'TL Parameters and Modeling Calculator';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.LLabel = uilabel(app.ResultsPanel);
            app.LLabel.Text = 'L';
            app.CLabel = uilabel(app.ResultsPanel);
            app.CLabel.Text = 'C';
            app.InductanceperphaseHkmLabel = uilabel(app.ResultsPanel);
            app.InductanceperphaseHkmLabel.Text = 'Inductance per phase (H/km)';
            app.CapacitanceperphaseFkmLabel = uilabel(app.ResultsPanel);
            app.CapacitanceperphaseFkmLabel.Text = 'Capacitance per phase (F/km)';
            app.NoteweassumetranspostionofthelineandweuseSIunitsLabel = uilabel(app.ResultsPanel);
            app.NoteweassumetranspostionofthelineandweuseSIunitsLabel.Text = 'Note: we assume transpostion of the line and  use only SI units at 25 degree';
            app.ResistanceohmkmLabel = uilabel(app.ResultsPanel);
            app.ResistanceohmkmLabel.Text = 'Resistance (ohm/km)';
            app.RLabel = uilabel(app.ResultsPanel);
            app.RLabel.Text = 'R';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ConductorTypeButtonGroup = uibuttongroup(app.Panel_2);
            app.ConductorTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConductorTypeButtonGroupSelectionChanged, true);
            app.ConductorTypeButtonGroup.Title = 'Conductor Type';
            app.StrandedButton = uiradiobutton(app.ConductorTypeButtonGroup);
            app.StrandedButton.Text = 'Stranded';
            app.StrandedButton.Value = true;
            app.SolidButton = uiradiobutton(app.ConductorTypeButtonGroup);
            app.SolidButton.Text = 'Solid';
            app.ConductorSpacingButtonGroup = uibuttongroup(app.Panel_2);
            app.ConductorSpacingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConductorSpacingButtonGroupSelectionChanged, true);
            app.ConductorSpacingButtonGroup.Title = 'Conductor Spacing';
            app.unsymmetricalButton = uiradiobutton(app.ConductorSpacingButtonGroup);
            app.unsymmetricalButton.Text = 'unsymmetrical';
            app.unsymmetricalButton.Value = true;
            app.symmetricalButton = uiradiobutton(app.ConductorSpacingButtonGroup);
            app.symmetricalButton.Text = 'symmetrical';
            app.ConductorTypeButtonGroup_2 = uibuttongroup(app.Panel_2);
            app.ConductorTypeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ConductorTypeButtonGroup_2SelectionChanged, true);
            app.ConductorTypeButtonGroup_2.Title = 'Conductor Type';
            app.BundledButton_3 = uiradiobutton(app.ConductorTypeButtonGroup_2);
            app.BundledButton_3.Text = 'Bundled';
            app.BundledButton_3.Value = true;
            app.SingleButton_2 = uiradiobutton(app.ConductorTypeButtonGroup_2);
            app.SingleButton_2.Text = 'Single';
            app.DistancesforunsymmetricalspacingPanel = uipanel(app.Panel_2);
            app.DistancesforunsymmetricalspacingPanel.Title = 'Distances for unsymmetrical spacing';
            app.D1EditFieldLabel = uilabel(app.DistancesforunsymmetricalspacingPanel);
            app.D1EditFieldLabel.Text = 'D1';
            app.D1EditField = uieditfield(app.DistancesforunsymmetricalspacingPanel, 'numeric');
            app.D2EditFieldLabel = uilabel(app.DistancesforunsymmetricalspacingPanel);
            app.D2EditFieldLabel.Text = 'D2';
            app.D2EditField = uieditfield(app.DistancesforunsymmetricalspacingPanel, 'numeric');
            app.D3EditFieldLabel = uilabel(app.DistancesforunsymmetricalspacingPanel);
            app.D3EditFieldLabel.Text = 'D3';
            app.D3EditField = uieditfield(app.DistancesforunsymmetricalspacingPanel, 'numeric');
            app.BundledPanel = uipanel(app.Panel_2);
            app.BundledPanel.Title = 'Bundled';
            app.NumberofBundlesButtonGroup = uibuttongroup(app.BundledPanel);
            app.NumberofBundlesButtonGroup.Title = 'Number of Bundles';
            app.Button = uiradiobutton(app.NumberofBundlesButtonGroup);
            app.Button.Text = '2';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.NumberofBundlesButtonGroup);
            app.Button_2.Text = '3';
            app.Button_3 = uiradiobutton(app.NumberofBundlesButtonGroup);
            app.Button_3.Text = '4';
            app.BundleDistanceEditFieldLabel = uilabel(app.BundledPanel);
            app.BundleDistanceEditFieldLabel.Text = 'Bundle Distance';
            app.BundleDistanceEditField = uieditfield(app.BundledPanel, 'numeric');
            app.CapacitanceandInductanceCalculatuinsPanel = uipanel(app.Panel_2);
            app.CapacitanceandInductanceCalculatuinsPanel.Title = 'Capacitance and Inductance Calculatuins';
            app.InductanceGMREditFieldLabel = uilabel(app.CapacitanceandInductanceCalculatuinsPanel);
            app.InductanceGMREditFieldLabel.Text = 'Inductance GMR ';
            app.InductanceGMREditField = uieditfield(app.CapacitanceandInductanceCalculatuinsPanel, 'numeric');
            app.ConductorRadiusEditFieldLabel = uilabel(app.CapacitanceandInductanceCalculatuinsPanel);
            app.ConductorRadiusEditFieldLabel.Enable = 'off';
            app.ConductorRadiusEditFieldLabel.Text = 'Conductor Radius';
            app.ConductorRadiusEditField = uieditfield(app.CapacitanceandInductanceCalculatuinsPanel, 'numeric');
            app.ConductorRadiusEditField.Enable = 'off';
            app.ConductorDistanceforsymmetricallineEditFieldLabel = uilabel(app.CapacitanceandInductanceCalculatuinsPanel);
            app.ConductorDistanceforsymmetricallineEditFieldLabel.Enable = 'off';
            app.ConductorDistanceforsymmetricallineEditFieldLabel.Text = 'Conductor Distance (for symmetrical line)';
            app.ConductorDistanceforsymmetricallineEditField = uieditfield(app.CapacitanceandInductanceCalculatuinsPanel, 'numeric');
            app.ConductorDistanceforsymmetricallineEditField.Enable = 'off';
            app.CapacitanceGMREditFieldLabel = uilabel(app.CapacitanceandInductanceCalculatuinsPanel);
            app.CapacitanceGMREditFieldLabel.Text = 'Capacitance GMR ';
            app.CapacitanceGMREditField = uieditfield(app.CapacitanceandInductanceCalculatuinsPanel, 'numeric');
            app.ResistanceCalculationPanel = uipanel(app.Panel_2);
            app.ResistanceCalculationPanel.Title = 'Resistance Calculation';
            app.SkinEffectCheckBox = uicheckbox(app.ResistanceCalculationPanel);
            app.SkinEffectCheckBox.Text = 'Skin Effect';
            app.SpiralEffectCheckBox = uicheckbox(app.ResistanceCalculationPanel);
            app.SpiralEffectCheckBox.Text = 'Spiral Effect';
            app.RadiusEditFieldLabel = uilabel(app.ResistanceCalculationPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.ResistanceCalculationPanel, 'numeric');
            app.NumberoflayrsEditFieldLabel = uilabel(app.ResistanceCalculationPanel);
            app.NumberoflayrsEditFieldLabel.Text = 'Number of layrs';
            app.NumberoflayrsEditField = uieditfield(app.ResistanceCalculationPanel, 'numeric');
            app.ResistivityEditFieldLabel = uilabel(app.ResistanceCalculationPanel);
            app.ResistivityEditFieldLabel.Text = 'Resistivity';
            app.ResistivityEditField = uieditfield(app.ResistanceCalculationPanel, 'numeric');
            app.calculateTLParametersRLCButton = uibutton(app.Panel_2, 'push');
            app.calculateTLParametersRLCButton.ButtonPushedFcn = createCallbackFcn(app, @calculateTLParametersRLCButtonPushed, true);
            app.calculateTLParametersRLCButton.Text = 'calculate TL Parameters (R,L,C)';
            app.ABCDParameterPanel = uipanel(app.UIFigure);
            app.ABCDParameterPanel.Title = 'ABCD Parameter';
            app.CalculateTLABCDParametersButton = uibutton(app.ABCDParameterPanel, 'push');
            app.CalculateTLABCDParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateTLABCDParametersButtonPushed, true);
            app.CalculateTLABCDParametersButton.Text = 'Calculate TL ABCD Parameters';
            app.RLCandLengthforABCDCalculationPanel = uipanel(app.ABCDParameterPanel);
            app.RLCandLengthforABCDCalculationPanel.Title = 'RLC and Length for ABCD Calculation';
            app.ResistanceohmKmEditFieldLabel = uilabel(app.RLCandLengthforABCDCalculationPanel);
            app.ResistanceohmKmEditFieldLabel.Enable = 'off';
            app.ResistanceohmKmEditFieldLabel.Text = 'Resistance (ohm/Km)';
            app.ResistanceohmKmEditField = uieditfield(app.RLCandLengthforABCDCalculationPanel, 'numeric');
            app.ResistanceohmKmEditField.Enable = 'off';
            app.CapacitanceFKmEditFieldLabel = uilabel(app.RLCandLengthforABCDCalculationPanel);
            app.CapacitanceFKmEditFieldLabel.Enable = 'off';
            app.CapacitanceFKmEditFieldLabel.Text = 'Capacitance (F/Km)';
            app.CapacitanceFKmEditField = uieditfield(app.RLCandLengthforABCDCalculationPanel, 'numeric');
            app.CapacitanceFKmEditField.Enable = 'off';
            app.TLModelDropDownLabel = uilabel(app.RLCandLengthforABCDCalculationPanel);
            app.TLModelDropDownLabel.Text = 'TL Model';
            app.TLModelDropDown = uidropdown(app.RLCandLengthforABCDCalculationPanel);
            app.TLModelDropDown.Items = {'Short', 'pi Medium', 'T Medium', 'Long'};
            app.TLModelDropDown.Value = 'Short';
            app.InductanceHKmEditFieldLabel = uilabel(app.RLCandLengthforABCDCalculationPanel);
            app.InductanceHKmEditFieldLabel.Enable = 'off';
            app.InductanceHKmEditFieldLabel.Text = 'Inductance (H/Km)';
            app.InductanceHKmEditField = uieditfield(app.RLCandLengthforABCDCalculationPanel, 'numeric');
            app.InductanceHKmEditField.Enable = 'off';
            app.LengthKmEditFieldLabel = uilabel(app.RLCandLengthforABCDCalculationPanel);
            app.LengthKmEditFieldLabel.Text = 'Length (Km)';
            app.LengthKmEditField = uieditfield(app.RLCandLengthforABCDCalculationPanel, 'numeric');
            app.FrequencyHzEditFieldLabel = uilabel(app.RLCandLengthforABCDCalculationPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.RLCandLengthforABCDCalculationPanel, 'numeric');
            app.AParameter = uilabel(app.ABCDParameterPanel);
            app.AParameter.Text = '1';
            app.ALabel = uilabel(app.ABCDParameterPanel);
            app.ALabel.Text = 'A';
            app.BParameter = uilabel(app.ABCDParameterPanel);
            app.BParameter.Text = '1';
            app.BLabel = uilabel(app.ABCDParameterPanel);
            app.BLabel.Text = 'B';
            app.CParameter = uilabel(app.ABCDParameterPanel);
            app.CParameter.Text = '1';
            app.CLabel_2 = uilabel(app.ABCDParameterPanel);
            app.CLabel_2.Text = 'C';
            app.DParameter = uilabel(app.ABCDParameterPanel);
            app.DParameter.Text = '1';
            app.DLabel = uilabel(app.ABCDParameterPanel);
            app.DLabel.Text = 'D';
            app.ButtonGroup = uibuttongroup(app.ABCDParameterPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged3, true);
            app.UseCalculatedRLCButton = uiradiobutton(app.ButtonGroup);
            app.UseCalculatedRLCButton.Text = 'Use Calculated RLC';
            app.UseCalculatedRLCButton.Value = true;
            app.EnterNewRLCButton = uiradiobutton(app.ButtonGroup);
            app.EnterNewRLCButton.Text = 'Enter New RLC';
            app.NameMohamedAhmedMahmoudMenazaaLabel = uilabel(app.UIFigure);
            app.NameMohamedAhmedMahmoudMenazaaLabel.Text = 'Name : Mohamed Ahmed Mahmoud Menazaa';
            app.SendingEndCalculationPanel = uipanel(app.UIFigure);
            app.SendingEndCalculationPanel.Title = 'Sending End Calculation';
            app.RLCandLengthforABCDCalculationPanel_2 = uipanel(app.SendingEndCalculationPanel);
            app.RLCandLengthforABCDCalculationPanel_2.Title = 'RLC and Length for ABCD Calculation';
            app.PowerWattEditFieldLabel = uilabel(app.RLCandLengthforABCDCalculationPanel_2);
            app.PowerWattEditFieldLabel.Text = 'Power (Watt)';
            app.PowerWattEditField = uieditfield(app.RLCandLengthforABCDCalculationPanel_2, 'numeric');
            app.VphaseReceivingVoltEditFieldLabel = uilabel(app.RLCandLengthforABCDCalculationPanel_2);
            app.VphaseReceivingVoltEditFieldLabel.Text = 'V phase Receiving (Volt)';
            app.VphaseReceivingVoltEditField = uieditfield(app.RLCandLengthforABCDCalculationPanel_2, 'numeric');
            app.pfDropDownLabel = uilabel(app.RLCandLengthforABCDCalculationPanel_2);
            app.pfDropDownLabel.Text = 'pf';
            app.pfDropDown = uidropdown(app.RLCandLengthforABCDCalculationPanel_2);
            app.pfDropDown.Items = {'Lead', 'Lag'};
            app.pfDropDown.Value = 'Lead';
            app.PowerFactorEditFieldLabel = uilabel(app.RLCandLengthforABCDCalculationPanel_2);
            app.PowerFactorEditFieldLabel.Text = 'Power Factor';
            app.PowerFactorEditField = uieditfield(app.RLCandLengthforABCDCalculationPanel_2, 'numeric');
            app.CalculateSendingEndParametersButton = uibutton(app.SendingEndCalculationPanel, 'push');
            app.CalculateSendingEndParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSendingEndParametersButtonPushed, true);
            app.CalculateSendingEndParametersButton.Text = 'Calculate Sending End Parameters';
            app.VSending = uilabel(app.SendingEndCalculationPanel);
            app.VSending.Text = '1';
            app.VphaseSendingVoltLabel = uilabel(app.SendingEndCalculationPanel);
            app.VphaseSendingVoltLabel.Text = 'V phase Sending (Volt)';
            app.ISending = uilabel(app.SendingEndCalculationPanel);
            app.ISending.Text = '1';
            app.IphaseSendingALabel = uilabel(app.SendingEndCalculationPanel);
            app.IphaseSendingALabel.Text = 'I phase Sending (A)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABCD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end