classdef Double_Circuit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConductorRadiusEditField       matlab.ui.control.NumericEditField
        ConductorRadiusEditFieldLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        DccEditField                   matlab.ui.control.NumericEditField
        DccEditFieldLabel              matlab.ui.control.Label
        DbbEditField                   matlab.ui.control.NumericEditField
        DbbEditFieldLabel              matlab.ui.control.Label
        DaaEditField                   matlab.ui.control.NumericEditField
        DaaEditFieldLabel              matlab.ui.control.Label
        InductanceperphaseFkmLabel     matlab.ui.control.Label
        InductanceperphaseHkmLabel     matlab.ui.control.Label
        calculateButton                matlab.ui.control.Button
        CLabel                         matlab.ui.control.Label
        LLabel                         matlab.ui.control.Label
        CircuitsSpacingEditField       matlab.ui.control.NumericEditField
        CircuitsSpacingEditFieldLabel  matlab.ui.control.Label
        LineSpacingEditField           matlab.ui.control.NumericEditField
        LineSpacingEditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            D = app.LineSpacingEditField.Value;
            W = app.CircuitsSpacingEditField.Value;
            D_W = ((D^2)+(W^2))^(1/2);
            GMD = (4*(D^6)*(W^2)*(D_W^4))^(1/12);
            d_a = app.DaaEditField.Value;
            d_b = app.DbbEditField.Value;
            d_c = app.DccEditField.Value;
            radius = app.ConductorRadiusEditField.Value;
            radius_L = radius*0.7788;
            GMR_a_L = (d_a*radius_L)^(1/2);
            GMR_b_L = (d_b*radius_L)^(1/2);
            GMR_c_L = (d_c*radius_L)^(1/2);
            GMR_a = (d_a*radius)^(1/2);
            GMR_b = (d_b*radius)^(1/2);
            GMR_c = (d_c*radius)^(1/2);
            GMR = (GMR_a*GMR_b*GMR_c)^(1/3);
            GMR_L = (GMR_a_L*GMR_b_L*GMR_c_L)^(1/3);
            Capacitance_Result = (2*pi* 8.854*(10^(-12)))/(log(GMD/GMR))*1000;
            app.CLabel.Text = string(Capacitance_Result);
            Inductance_Result = 2*(10^(-7))*log(GMD/GMR_L)*1000;
            app.LLabel.Text = string(Inductance_Result);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LineSpacingEditFieldLabel = uilabel(app.UIFigure);
            app.LineSpacingEditFieldLabel.Text = 'Line Spacing';
            app.LineSpacingEditField = uieditfield(app.UIFigure, 'numeric');
            app.CircuitsSpacingEditFieldLabel = uilabel(app.UIFigure);
            app.CircuitsSpacingEditFieldLabel.Text = 'Circuits Spacing';
            app.CircuitsSpacingEditField = uieditfield(app.UIFigure, 'numeric');
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = 'C';
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.InductanceperphaseHkmLabel = uilabel(app.UIFigure);
            app.InductanceperphaseHkmLabel.Text = 'Inductance per phase (H/km)';
            app.InductanceperphaseFkmLabel = uilabel(app.UIFigure);
            app.InductanceperphaseFkmLabel.Text = 'Inductance per phase (F/km)';
            app.Panel = uipanel(app.UIFigure);
            app.DaaEditFieldLabel = uilabel(app.Panel);
            app.DaaEditFieldLabel.Text = 'D(a-a'')';
            app.DaaEditField = uieditfield(app.Panel, 'numeric');
            app.DbbEditFieldLabel = uilabel(app.Panel);
            app.DbbEditFieldLabel.Text = 'D(b-b'')';
            app.DbbEditField = uieditfield(app.Panel, 'numeric');
            app.DccEditFieldLabel = uilabel(app.Panel);
            app.DccEditFieldLabel.Text = 'D(c-c'')';
            app.DccEditField = uieditfield(app.Panel, 'numeric');
            app.ConductorRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.ConductorRadiusEditFieldLabel.Text = 'Conductor Radius';
            app.ConductorRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Double_Circuit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end