classdef Resistance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TransmissionLineResistanceCalculatorLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        NumberofbundlesEditField        matlab.ui.control.NumericEditField
        NumberofbundlesEditFieldLabel   matlab.ui.control.Label
        ResistivityEditField            matlab.ui.control.NumericEditField
        ResistivityEditFieldLabel       matlab.ui.control.Label
        NumberoflayrsEditField          matlab.ui.control.NumericEditField
        NumberoflayrsEditFieldLabel     matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        SpiralEffectCheckBox            matlab.ui.control.CheckBox
        SkinEffectCheckBox              matlab.ui.control.CheckBox
        ConductorTypeButtonGroup_2      matlab.ui.container.ButtonGroup
        StrandedButton                  matlab.ui.control.RadioButton
        SolidButton                     matlab.ui.control.RadioButton
        ConductorTypeButtonGroup        matlab.ui.container.ButtonGroup
        BundledButton                   matlab.ui.control.RadioButton
        SingleButton                    matlab.ui.control.RadioButton
        NoteWeuseonlySIunitsat20CLabel  matlab.ui.control.Label
        ResistanceohmLabel              matlab.ui.control.Label
        NameMohamedAhmedMahmoudMenazaaLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ConductorTypeButtonGroup
        function ConductorTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConductorTypeButtonGroup.SelectedObject;
            
            if selectedButton == app.SingleButton
                app.NumberofbundlesEditField.Enable = 'of';
            else 
                app.NumberofbundlesEditField.Enable = 'on';
            end
        end

        % Selection changed function: ConductorTypeButtonGroup_2
        function ConductorTypeButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ConductorTypeButtonGroup_2.SelectedObject;
            if selectedButton == app.StrandedButton
                app.RadiusEditFieldLabel.Text = 'Strand Radius';
                app.NumberoflayrsEditField.Enable = 'on';
            else 
                app.RadiusEditFieldLabel.Text = 'Radius';
                app.NumberoflayrsEditField.Enable = 'of';
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Bundled = app.ConductorTypeButtonGroup.SelectedObject;
            Stranded = app.ConductorTypeButtonGroup_2.SelectedObject;
            Radius = app.RadiusEditField.Value;
            Length  = app.LengthEditField.Value;
            Resistivity = app.ResistivityEditField.Value;
            if (Bundled == app.BundledButton)
                if Stranded == app.StrandedButton
                    Layers_Num = app.NumberoflayrsEditField.Value;
                    Bundles_Num = app.NumberofbundlesEditField.Value;
                    Area = pi*(Radius^2)*(3*Layers_Num*(Layers_Num+1)+1)*Bundles_Num;
                    Resistance = (Resistivity*Length)/Area;
                    if (app.SkinEffectCheckBox.Value)
                        Resistance = Resistance * 1.11;
                    end
                    if (app.SpiralEffectCheckBox.Value)
                        Resistance = Resistance * 1.02;
                    end
                    app.Label.Text = string(Resistance);
                else
                    Bundles_Num = app.NumberofbundlesEditField.Value;
                    Area = pi*(Radius^2)*Bundles_Num;
                    Resistance = (Resistivity*Length)/Area;
                    if (app.SkinEffectCheckBox.Value)
                        Resistance = Resistance * 1.11;
                    end
                    if (app.SpiralEffectCheckBox.Value)
                        Resistance = Resistance * 1.02;
                    end
                    app.Label.Text = string(Resistance);
                end
            else
                if Stranded == app.StrandedButton
                    Layers_Num = app.NumberoflayrsEditField.Value;
                    Area = pi*(Radius^2)*(3*Layers_Num*(Layers_Num+1)+1);
                    Resistance = (Resistivity*Length)/Area;
                    if (app.SkinEffectCheckBox.Value)
                        Resistance = Resistance * 1.11;
                    end
                    if (app.SpiralEffectCheckBox.Value)
                        Resistance = Resistance * 1.02;
                    end
                    app.Label.Text = string(Resistance);
                else
                    Area = pi*(Radius^2);
                    Resistance = (Resistivity*Length)/Area;
                    if (app.SkinEffectCheckBox.Value)
                        Resistance = Resistance * 1.11;
                    end
                    if (app.SpiralEffectCheckBox.Value)
                        Resistance = Resistance * 1.02;
                    end
                    app.Label.Text = string(Resistance);
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.NameMohamedAhmedMahmoudMenazaaLabel = uilabel(app.UIFigure);
            app.NameMohamedAhmedMahmoudMenazaaLabel.Text = 'Name : Mohamed Ahmed Mahmoud Menazaa';
            app.ResistanceohmLabel = uilabel(app.UIFigure);
            app.ResistanceohmLabel.Text = 'Resistance (ohm)';
            app.NoteWeuseonlySIunitsat20CLabel = uilabel(app.UIFigure);
            app.NoteWeuseonlySIunitsat20CLabel.Text = 'Note : We use only SI units at 20 °C';
            app.Panel = uipanel(app.UIFigure);
            app.ConductorTypeButtonGroup = uibuttongroup(app.Panel);
            app.ConductorTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConductorTypeButtonGroupSelectionChanged, true);
            app.ConductorTypeButtonGroup.Title = 'Conductor Type';
            app.SingleButton = uiradiobutton(app.ConductorTypeButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.BundledButton = uiradiobutton(app.ConductorTypeButtonGroup);
            app.BundledButton.Text = 'Bundled';
            app.ConductorTypeButtonGroup_2 = uibuttongroup(app.Panel);
            app.ConductorTypeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ConductorTypeButtonGroup_2SelectionChanged, true);
            app.ConductorTypeButtonGroup_2.Title = 'Conductor Type';
            app.SolidButton = uiradiobutton(app.ConductorTypeButtonGroup_2);
            app.SolidButton.Text = 'Solid';
            app.SolidButton.Value = true;
            app.StrandedButton = uiradiobutton(app.ConductorTypeButtonGroup_2);
            app.StrandedButton.Text = 'Stranded';
            app.SkinEffectCheckBox = uicheckbox(app.Panel);
            app.SkinEffectCheckBox.Text = 'Skin Effect';
            app.SpiralEffectCheckBox = uicheckbox(app.Panel);
            app.SpiralEffectCheckBox.Text = 'Spiral Effect';
            app.RadiusEditFieldLabel = uilabel(app.Panel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.Panel, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.Panel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.Panel, 'numeric');
            app.NumberoflayrsEditFieldLabel = uilabel(app.Panel);
            app.NumberoflayrsEditFieldLabel.Text = 'Number of layrs';
            app.NumberoflayrsEditField = uieditfield(app.Panel, 'numeric');
            app.ResistivityEditFieldLabel = uilabel(app.Panel);
            app.ResistivityEditFieldLabel.Text = 'Resistivity';
            app.ResistivityEditField = uieditfield(app.Panel, 'numeric');
            app.NumberofbundlesEditFieldLabel = uilabel(app.Panel);
            app.NumberofbundlesEditFieldLabel.Text = 'Number of bundles';
            app.NumberofbundlesEditField = uieditfield(app.Panel, 'numeric');
            app.TransmissionLineResistanceCalculatorLabel = uilabel(app.UIFigure);
            app.TransmissionLineResistanceCalculatorLabel.Text = 'Transmission Line Resistance Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Resistance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end