classdef TL_Inductance_Capacitance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AuthorPanel                    matlab.ui.container.Panel
        MohamedAhmedMahmoudMenazaaLabel  matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        calculateButton                matlab.ui.control.Button
        Panel_3                        matlab.ui.container.Panel
        CapacitanceGMREditField        matlab.ui.control.NumericEditField
        CapacitanceGMREditFieldLabel   matlab.ui.control.Label
        ConductorDistanceforsymmetricallineEditField  matlab.ui.control.NumericEditField
        ConductorDistanceforsymmetricallineEditFieldLabel  matlab.ui.control.Label
        ConductorRadiusEditField       matlab.ui.control.NumericEditField
        ConductorRadiusEditFieldLabel  matlab.ui.control.Label
        InductanceGMREditField         matlab.ui.control.NumericEditField
        InductanceGMREditFieldLabel    matlab.ui.control.Label
        BundledPanel                   matlab.ui.container.Panel
        BundleDistanceEditField        matlab.ui.control.NumericEditField
        BundleDistanceEditFieldLabel   matlab.ui.control.Label
        NumberofBundlesButtonGroup     matlab.ui.container.ButtonGroup
        Button_3                       matlab.ui.control.RadioButton
        Button_2                       matlab.ui.control.RadioButton
        Button                         matlab.ui.control.RadioButton
        DistancesforunsymmetricalspacingPanel  matlab.ui.container.Panel
        D3EditField                    matlab.ui.control.NumericEditField
        D3EditFieldLabel               matlab.ui.control.Label
        D2EditField                    matlab.ui.control.NumericEditField
        D2EditFieldLabel               matlab.ui.control.Label
        D1EditField                    matlab.ui.control.NumericEditField
        D1EditFieldLabel               matlab.ui.control.Label
        ConductorTypeButtonGroup_2     matlab.ui.container.ButtonGroup
        SingleButton_2                 matlab.ui.control.RadioButton
        BundledButton_3                matlab.ui.control.RadioButton
        ConductorSpacingButtonGroup    matlab.ui.container.ButtonGroup
        symmetricalButton              matlab.ui.control.RadioButton
        unsymmetricalButton            matlab.ui.control.RadioButton
        ConductorTypeButtonGroup       matlab.ui.container.ButtonGroup
        SolidButton                    matlab.ui.control.RadioButton
        StrandedButton                 matlab.ui.control.RadioButton
        ResultPanel                    matlab.ui.container.Panel
        NoteweassumetranspostionofthelineandweuseSIunitsLabel  matlab.ui.control.Label
        InductanceperphaseFmLabel      matlab.ui.control.Label
        InductanceperphaseHmLabel      matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        LLabel                         matlab.ui.control.Label
        TLInductanceandCapacitanceCalculatorLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ConductorTypeButtonGroup
        function ConductorTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConductorTypeButtonGroup.SelectedObject;
            if (selectedButton == app.SolidButton)
                app.InductanceGMREditField.Enable = 'off';
                app.InductanceGMREditFieldLabel.Enable = 'off';
                app.CapacitanceGMREditField.Enable = 'off';
                app.CapacitanceGMREditFieldLabel.Enable = 'off';
                app.ConductorRadiusEditField.Enable = 'on';
                app.ConductorRadiusEditFieldLabel.Enable = 'on';
            else
                app.InductanceGMREditField.Enable = 'on';
                app.InductanceGMREditFieldLabel.Enable = 'on';
                app.CapacitanceGMREditField.Enable = 'on';
                app.CapacitanceGMREditFieldLabel.Enable = 'on';
                app.ConductorRadiusEditField.Enable = 'off';
                app.ConductorRadiusEditFieldLabel.Enable = 'off';
            end
        end

        % Selection changed function: ConductorSpacingButtonGroup
        function ConductorSpacingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConductorSpacingButtonGroup.SelectedObject;
            if (selectedButton == app.symmetricalButton)
                app.DistancesforunsymmetricalspacingPanel.Enable = 'off';
                app.ConductorDistanceforsymmetricallineEditField.Enable = 'on';
                app.ConductorDistanceforsymmetricallineEditFieldLabel.Enable = 'on';
            else
                app.ConductorDistanceforsymmetricallineEditField.Enable = 'off';
                app.ConductorDistanceforsymmetricallineEditFieldLabel.Enable =  'off';
                app.DistancesforunsymmetricalspacingPanel.Enable = 'on';
            end
        end

        % Selection changed function: ConductorTypeButtonGroup_2
        function ConductorTypeButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ConductorTypeButtonGroup_2.SelectedObject;
            if (selectedButton == app.SingleButton_2)
                app.BundledPanel.Enable = 'off';
            else
                app.BundledPanel.Enable = 'on';
            end
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            Symmetry = app.ConductorSpacingButtonGroup.SelectedObject;
            IsStranded = app.ConductorTypeButtonGroup.SelectedObject;
            IsBundled = app.ConductorTypeButtonGroup_2.SelectedObject;
            if (Symmetry == app.symmetricalButton)
                GMD = app.ConductorDistanceforsymmetricallineEditField.Value;
            else
                D1 = app.D1EditField.Value;
                D2 = app.D2EditField.Value;
                D3 = app.D3EditField.Value;
                GMD = (D1*D2*D3)^(1/3);
            end
            L_GMR = 0;
            C_GMR = 0;
            if (IsStranded == app.StrandedButton)
                L_GMR = app.InductanceGMREditField.value;
                C_GMR = app.CapacitanceGMREditField.Value;
            else
                radius = app.ConductorRadiusEditField.Value;
                r_dash = radius*0.7788;
                if(IsBundled == app.BundledButton_3)
                    Bundles_Distance = app.BundleDistanceEditField.Value;
                    switch(app.NumberofBundlesButtonGroup.SelectedObject.Text)
                        case '2'
                            L_GMR = (r_dash*Bundles_Distance)^(1/2);
                            C_GMR = (radius*Bundles_Distance)^(1/2);
                        case '3'
                            L_GMR = (r_dash*(Bundles_Distance^2))^(1/3);
                            C_GMR = (radius*(Bundles_Distance^2))^(1/3);
                            
                        case '4'
                            L_GMR = 1.09*(r_dash*(Bundles_Distance^3))^(1/4);
                            C_GMR = 1.09*(radius*(Bundles_Distance^3))^(1/4);  
                    end
                else
                    L_GMR = r_dash;
                    C_GMR = radius;
                end
            end
            if (L_GMR==0 || C_GMR==0)
                app.LLabel.Text = "GMR = 0 Error";
                app.CLabel.Text = "GMR = 0 Error";
            else
               
                Capacitance_Result = (2*pi* 8.854*(10^(-12)))/(log(GMD/C_GMR));
                app.CLabel.Text = string(Capacitance_Result);
                Inductance_Result = 2*(10^(-7))*log(GMD/L_GMR);
                app.LLabel.Text = string(Inductance_Result);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TLInductanceandCapacitanceCalculatorLabel = uilabel(app.UIFigure);
            app.TLInductanceandCapacitanceCalculatorLabel.Text = 'TL Inductance and Capacitance Calculator';
            app.ResultPanel = uipanel(app.UIFigure);
            app.ResultPanel.Title = 'Result';
            app.LLabel = uilabel(app.ResultPanel);
            app.LLabel.Text = 'L';
            app.CLabel = uilabel(app.ResultPanel);
            app.CLabel.Text = 'C';
            app.InductanceperphaseHmLabel = uilabel(app.ResultPanel);
            app.InductanceperphaseHmLabel.Text = 'Inductance per phase (H/m)';
            app.InductanceperphaseFmLabel = uilabel(app.ResultPanel);
            app.InductanceperphaseFmLabel.Text = 'Inductance per phase (F/m)';
            app.NoteweassumetranspostionofthelineandweuseSIunitsLabel = uilabel(app.ResultPanel);
            app.NoteweassumetranspostionofthelineandweuseSIunitsLabel.Text = 'Note: we assume transpostion of the line and we use SI units';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ConductorTypeButtonGroup = uibuttongroup(app.Panel_2);
            app.ConductorTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConductorTypeButtonGroupSelectionChanged, true);
            app.ConductorTypeButtonGroup.Title = 'Conductor Type';
            app.StrandedButton = uiradiobutton(app.ConductorTypeButtonGroup);
            app.StrandedButton.Text = 'Stranded';
            app.StrandedButton.Value = true;
            app.SolidButton = uiradiobutton(app.ConductorTypeButtonGroup);
            app.SolidButton.Text = 'Solid';
            app.ConductorSpacingButtonGroup = uibuttongroup(app.Panel_2);
            app.ConductorSpacingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConductorSpacingButtonGroupSelectionChanged, true);
            app.ConductorSpacingButtonGroup.Title = 'Conductor Spacing';
            app.unsymmetricalButton = uiradiobutton(app.ConductorSpacingButtonGroup);
            app.unsymmetricalButton.Text = 'unsymmetrical';
            app.unsymmetricalButton.Value = true;
            app.symmetricalButton = uiradiobutton(app.ConductorSpacingButtonGroup);
            app.symmetricalButton.Text = 'symmetrical';
            app.ConductorTypeButtonGroup_2 = uibuttongroup(app.Panel_2);
            app.ConductorTypeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ConductorTypeButtonGroup_2SelectionChanged, true);
            app.ConductorTypeButtonGroup_2.Title = 'Conductor Type';
            app.BundledButton_3 = uiradiobutton(app.ConductorTypeButtonGroup_2);
            app.BundledButton_3.Text = 'Bundled';
            app.BundledButton_3.Value = true;
            app.SingleButton_2 = uiradiobutton(app.ConductorTypeButtonGroup_2);
            app.SingleButton_2.Text = 'Single';
            app.DistancesforunsymmetricalspacingPanel = uipanel(app.Panel_2);
            app.DistancesforunsymmetricalspacingPanel.Title = 'Distances for unsymmetrical spacing';
            app.D1EditFieldLabel = uilabel(app.DistancesforunsymmetricalspacingPanel);
            app.D1EditFieldLabel.Text = 'D1';
            app.D1EditField = uieditfield(app.DistancesforunsymmetricalspacingPanel, 'numeric');
            app.D2EditFieldLabel = uilabel(app.DistancesforunsymmetricalspacingPanel);
            app.D2EditFieldLabel.Text = 'D2';
            app.D2EditField = uieditfield(app.DistancesforunsymmetricalspacingPanel, 'numeric');
            app.D3EditFieldLabel = uilabel(app.DistancesforunsymmetricalspacingPanel);
            app.D3EditFieldLabel.Text = 'D3';
            app.D3EditField = uieditfield(app.DistancesforunsymmetricalspacingPanel, 'numeric');
            app.BundledPanel = uipanel(app.Panel_2);
            app.BundledPanel.Title = 'Bundled';
            app.NumberofBundlesButtonGroup = uibuttongroup(app.BundledPanel);
            app.NumberofBundlesButtonGroup.Title = 'Number of Bundles';
            app.Button = uiradiobutton(app.NumberofBundlesButtonGroup);
            app.Button.Text = '2';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.NumberofBundlesButtonGroup);
            app.Button_2.Text = '3';
            app.Button_3 = uiradiobutton(app.NumberofBundlesButtonGroup);
            app.Button_3.Text = '4';
            app.BundleDistanceEditFieldLabel = uilabel(app.BundledPanel);
            app.BundleDistanceEditFieldLabel.Text = 'Bundle Distance';
            app.BundleDistanceEditField = uieditfield(app.BundledPanel, 'numeric');
            app.Panel_3 = uipanel(app.Panel_2);
            app.InductanceGMREditFieldLabel = uilabel(app.Panel_3);
            app.InductanceGMREditFieldLabel.Text = 'Inductance GMR ';
            app.InductanceGMREditField = uieditfield(app.Panel_3, 'numeric');
            app.ConductorRadiusEditFieldLabel = uilabel(app.Panel_3);
            app.ConductorRadiusEditFieldLabel.Enable = 'off';
            app.ConductorRadiusEditFieldLabel.Text = 'Conductor Radius';
            app.ConductorRadiusEditField = uieditfield(app.Panel_3, 'numeric');
            app.ConductorRadiusEditField.Enable = 'off';
            app.ConductorDistanceforsymmetricallineEditFieldLabel = uilabel(app.Panel_3);
            app.ConductorDistanceforsymmetricallineEditFieldLabel.Enable = 'off';
            app.ConductorDistanceforsymmetricallineEditFieldLabel.Text = 'Conductor Distance (for symmetrical line)';
            app.ConductorDistanceforsymmetricallineEditField = uieditfield(app.Panel_3, 'numeric');
            app.ConductorDistanceforsymmetricallineEditField.Enable = 'off';
            app.CapacitanceGMREditFieldLabel = uilabel(app.Panel_3);
            app.CapacitanceGMREditFieldLabel.Text = 'Capacitance GMR ';
            app.CapacitanceGMREditField = uieditfield(app.Panel_3, 'numeric');
            app.calculateButton = uibutton(app.Panel_2, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.AuthorPanel = uipanel(app.UIFigure);
            app.AuthorPanel.Title = 'Author';
            app.MohamedAhmedMahmoudMenazaaLabel = uilabel(app.AuthorPanel);
            app.MohamedAhmedMahmoudMenazaaLabel.Text = 'Mohamed Ahmed Mahmoud Menazaa ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TL_Inductance_Capacitance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end